classdef scadav1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Disconnectfromied3Button      matlab.ui.control.Button
        Connecttoied3Button           matlab.ui.control.Button
        Disconnectfromied2Button      matlab.ui.control.Button
        Connecttoied2Button           matlab.ui.control.Button
        Switch                        matlab.ui.control.ToggleSwitch
        SwitchLabel                   matlab.ui.control.Label
        DesiredVoltageEditField       matlab.ui.control.EditField
        DesiredVoltageEditFieldLabel  matlab.ui.control.Label
        Disconnectfromied1Button      matlab.ui.control.Button
        Gauge                         matlab.ui.control.Gauge
        GaugeLabel                    matlab.ui.control.Label
        Connecttoied1Button           matlab.ui.control.Button
    end

    
    properties (Access = private)
        updateTimer % Description
        iec % Description
        con1 % Description
        con2
        con3
    end
    
    methods (Access = private)
        
        function updateGauge(app)
            try

                result = app.iec.IedConnection_readFloatValue(app.con1,'simpleIOGenericIO/GGIO1.AnIn1.mag.f', app.iec.IEC61850_FC_MX);
                val = double(result{1});     % first element = float value
                err = result{2};             % second element = error code
                app.Gauge.Value = double(val);

    
            catch ME
                if ~isempty(app.updateTimer)
                       stop(app.updateTimer);
                       delete(app.updateTimer);
                       app.updateTimer = [];
                end
                uialert(app.UIFigure, "Connection Lost" + ME.message, "Error", 'Icon','error');

            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Connecttoied1Button
        function Connecttoied1ButtonPushed(app, event)
   try
        app.iec = py.importlib.import_module('pyiec61850');

        app.con1 = app.iec.IedConnection_create();
        err = app.iec.IedConnection_connect(app.con1, '172.17.36.120', int32(102));

        app.updateTimer = timer( ...
            'ExecutionMode', 'fixedRate', 'Period', 1.0, 'TimerFcn',@(~,~) app.updateGauge());
        start(app.updateTimer);


        if err == app.iec.IED_ERROR_OK
            uialert(app.UIFigure, 'Connection to IED1 successful!', 'IEC61850');
        end
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
    end


        end

        % Button pushed function: Disconnectfromied1Button
        function Disconnectfromied1ButtonPushed(app, event)
    if ~isempty(app.updateTimer)
        stop(app.updateTimer);
        delete(app.updateTimer);
        app.updateTimer = [];
    end
    if ~isempty(app.con1)
        app.iec.IedConnection_destroy(app.con1);
        app.con1 = [];
    end
    uialert(app.UIFigure, 'Disconnected from IEDServer1', 'IEC61850');


        end

        % Value changed function: DesiredVoltageEditField
        function DesiredVoltageEditFieldValueChanged(app, event)
            value = app.DesiredVoltageEditField.Value;
            
            tosend = app.iec.MmsValue_newVisibleString(value);

            app.iec.IedConnection_writeObject(app.con, 'simpleIOGenericIO/GGIO1.NamPlt.vendor', app.iec.IEC61850_FC_DC, tosend);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
    try
        if strcmp(app.Switch.Value, 'On')
            valBool = true;
        else
            valBool = false;
        end

        % create control object handle
        ctrl = app.iec.ControlObjectClient_create( ...
            'simpleIOGenericIO/GGIO1.SPCSO1', app.con);

        % make the MMS Boolean value
        val = app.iec.MmsValue_newBoolean(valBool);

        % perform control (Operate)
        result = app.iec.ControlObjectClient_operate(ctrl, val, py.int(0));
        if result == 0
            fprintf("Operate success: Switch set to %s\n", app.Switch.Value);
        else
            fprintf("Operate failed with code %d\n", int32(result));
        end

        % clean up
        app.iec.MmsValue_delete(val);
        app.iec.ControlObjectClient_destroy(ctrl);

    catch ME
        uialert(app.UIFigure, "Error: " + ME.message, "Operate Error", 'Icon', 'error');
    end

        end

        % Button pushed function: Connecttoied2Button
        function Connecttoied2ButtonPushed(app, event)
     try
        app.iec = py.importlib.import_module('pyiec61850');

        app.con2 = app.iec.IedConnection_create();
        err = app.iec.IedConnection_connect(app.con2, '172.17.36.120', int32(103));

        app.updateTimer = timer( ...
            'ExecutionMode', 'fixedRate', 'Period', 1.0, 'TimerFcn',@(~,~) app.updateGauge());
        start(app.updateTimer);


        if err == app.iec.IED_ERROR_OK
            uialert(app.UIFigure, 'Connection to IED2 successful!', 'IEC61850');
        end
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
    end       
        end

        % Button pushed function: Connecttoied3Button
        function Connecttoied3ButtonPushed(app, event)
     try
        app.iec = py.importlib.import_module('pyiec61850');

        app.con3 = app.iec.IedConnection_create();
        err = app.iec.IedConnection_connect(app.con3, '172.17.36.120', int32(104));

        app.updateTimer = timer( ...
            'ExecutionMode', 'fixedRate', 'Period', 1.0, 'TimerFcn',@(~,~) app.updateGauge());
        start(app.updateTimer);


        if err == app.iec.IED_ERROR_OK
            uialert(app.UIFigure, 'Connection to IED3 successful!', 'IEC61850');
        end
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
    end
        end

        % Button pushed function: Disconnectfromied2Button
        function Disconnectfromied2ButtonPushed(app, event)
     if ~isempty(app.updateTimer)
        stop(app.updateTimer);
        delete(app.updateTimer);
        app.updateTimer = [];
    end
    if ~isempty(app.con2)
        app.iec.IedConnection_destroy(app.con2);
        app.con2 = [];
    end
    uialert(app.UIFigure, 'Disconnected from IEDserver2', 'IEC61850');   
        end

        % Button pushed function: Disconnectfromied3Button
        function Disconnectfromied3ButtonPushed(app, event)
     if ~isempty(app.updateTimer)
        stop(app.updateTimer);
        delete(app.updateTimer);
        app.updateTimer = [];
    end
    if ~isempty(app.con3)
        app.iec.IedConnection_destroy(app.con3);
        app.con3 = [];
    end
    uialert(app.UIFigure, 'Disconnected from IEDserver3', 'IEC61850');               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Connecttoied1Button = uibutton(app.UIFigure, 'push');
            app.Connecttoied1Button.ButtonPushedFcn = createCallbackFcn(app, @Connecttoied1ButtonPushed, true);
            app.Connecttoied1Button.Text = 'Connect to ied1';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Disconnectfromied1Button = uibutton(app.UIFigure, 'push');
            app.Disconnectfromied1Button.ButtonPushedFcn = createCallbackFcn(app, @Disconnectfromied1ButtonPushed, true);
            app.Disconnectfromied1Button.Text = 'Disconnect from ied1';
            app.DesiredVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredVoltageEditFieldLabel.Text = 'DesiredVoltage';
            app.DesiredVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.DesiredVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredVoltageEditFieldValueChanged, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Connecttoied2Button = uibutton(app.UIFigure, 'push');
            app.Connecttoied2Button.ButtonPushedFcn = createCallbackFcn(app, @Connecttoied2ButtonPushed, true);
            app.Connecttoied2Button.Text = 'Connect to ied2';
            app.Disconnectfromied2Button = uibutton(app.UIFigure, 'push');
            app.Disconnectfromied2Button.ButtonPushedFcn = createCallbackFcn(app, @Disconnectfromied2ButtonPushed, true);
            app.Disconnectfromied2Button.Text = 'Disconnect from ied2';
            app.Connecttoied3Button = uibutton(app.UIFigure, 'push');
            app.Connecttoied3Button.ButtonPushedFcn = createCallbackFcn(app, @Connecttoied3ButtonPushed, true);
            app.Connecttoied3Button.Text = 'Connect to ied3';
            app.Disconnectfromied3Button = uibutton(app.UIFigure, 'push');
            app.Disconnectfromied3Button.ButtonPushedFcn = createCallbackFcn(app, @Disconnectfromied3ButtonPushed, true);
            app.Disconnectfromied3Button.Text = 'Disconnect from ied3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scadav1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end